classdef Calc_LT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InicioTab                       matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        NodosTab                        matlab.ui.container.Tab
        Label_2                         matlab.ui.control.Label
        DatosdeentradaPanel             matlab.ui.container.Panel
        NmerodenodosEditFieldLabel      matlab.ui.control.Label
        NumNodosEditField               matlab.ui.control.NumericEditField
        VoltajebasekVLabel              matlab.ui.control.Label
        VoltBaseEditField               matlab.ui.control.NumericEditField
        PotenciabaseMVALabel            matlab.ui.control.Label
        PotBaseEditField                matlab.ui.control.NumericEditField
        TabladenodosPanel               matlab.ui.container.Panel
        TablaNodos                      matlab.ui.control.Table
        CargardatosdesdearchivoPanel    matlab.ui.container.Panel
        GuardarycontinuarButton         matlab.ui.control.Button
        SeleccionararchivoNodosButton   matlab.ui.control.Button
        DatosenPUNodosCheckBox          matlab.ui.control.CheckBox
        CalculoParametros               matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        DatosEntrada                    matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        LabelDropDown                   matlab.ui.control.Label
        TipoConductor                   matlab.ui.control.DropDown
        LabelNumericEditField           matlab.ui.control.Label
        LongitudLinea                   matlab.ui.control.NumericEditField
        Label8                          matlab.ui.control.Label
        TempOp                          matlab.ui.control.NumericEditField
        DistribucionPanel               matlab.ui.container.Panel
        Label2                          matlab.ui.control.Label
        Distribucion                    matlab.ui.control.DropDown
        Label9                          matlab.ui.control.Label
        Distancia                       matlab.ui.control.NumericEditField
        InterconexinPanel               matlab.ui.container.Panel
        IndicadorDeLT                   matlab.ui.control.Label
        ConNodoA                        matlab.ui.control.DropDown
        yLabel                          matlab.ui.control.Label
        ConNodoB                        matlab.ui.control.DropDown
        RLC                             matlab.ui.container.Panel
        ReactanciainductivaOhmsLabel_2  matlab.ui.control.Label
        Ind                             matlab.ui.control.TextArea
        BotonCalcRLC                    matlab.ui.control.Button
        BotonAgregarLT                  matlab.ui.control.Button
        BotonGuardarLT                  matlab.ui.control.Button
        LabelTextArea                   matlab.ui.control.Label
        Res                             matlab.ui.control.TextArea
        ReactanciacapacitivaOhmsLabel   matlab.ui.control.Label
        Cap                             matlab.ui.control.TextArea
        CargarparmetrosdesdearchivoPanel  matlab.ui.container.Panel
        SeleccionararchivoLineasButton  matlab.ui.control.Button
        TablaLT                         matlab.ui.container.Tab
        TablasResumen                   matlab.ui.container.Panel
        TablaLineas                     matlab.ui.control.Table
        Label17                         matlab.ui.control.Label
        EdicionLineas                   matlab.ui.container.Panel
        EliminarlneaDropDownLabel       matlab.ui.control.Label
        EliminarLineaDropDown           matlab.ui.control.DropDown
        EliminarLineaButton             matlab.ui.control.Button
        NRTab                           matlab.ui.container.Tab
        MtododeNewtonRaphsonPanel       matlab.ui.container.Panel
        CalcularNewtonRaphsonButton     matlab.ui.control.Button
        NmeromximodeiteracionesSpinnerLabel  matlab.ui.control.Label
        MaxItSpinner                    matlab.ui.control.Spinner
        UmbraldeerrormximoEditFieldLabel  matlab.ui.control.Label
        MaxErrorEditField               matlab.ui.control.NumericEditField
        Label17_2                       matlab.ui.control.Label
        PanelIteraciones                matlab.ui.container.Panel
        TablaIteraciones                matlab.ui.control.Table
        ResultadosTab                   matlab.ui.container.Tab
        Label17_3                       matlab.ui.control.Label
        PanelTablaFinal                 matlab.ui.container.Panel
        TablaResultadosFinales          matlab.ui.control.Table
        ExportardatosPanel              matlab.ui.container.Panel
        ExportardatosButton             matlab.ui.control.Button
        InfoNodosCheckBox               matlab.ui.control.CheckBox
        InfoLTCheckBox                  matlab.ui.control.CheckBox
        InfoIterCheckBox                matlab.ui.control.CheckBox
        InclurenelreporteLabel          matlab.ui.control.Label
        InfoSPCheckBox                  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        SeccTrans_glob
        RMG_glob % Description
        DMG_glob % Description
        Distrib_glob % Description
        ConductorTipo
        
        Long_in
        Vop_in
        Pcarga_in
        FP_in
        Temp_in
        Dist_in
        
        Res_calc
        Ind_calc
        Cap_calc
        XL
        BC
        Z_linea
        Y_linea
        V_op % Description
        I_op % Description
        
        
        %-------
        NumNodos
        TablaNodos_in;      %Tabla de tipo table con la información de los nodos
        NombresNodos;
        TiposNodos;
        VmagNodos;
        VangNodos;
        PNodos;
        QNodos;
        
        TablaLT_in;          % Tabla que tiene la información de las líneas de transmisión
        Num_Linea = 0; % Description
        NodoConectadoA; % Description
        NodoConectadoB; % Description
        Sbase
        Vbase
        
        VmagNodosPU_solved;
        VangNodosPU_solved;
        PNodosPU_solved;
        QNodosPU_solved;
    end

    methods (Access = private)
        
        function R_ca = getRca(app, long, secc_trans, num_hilos)
            Resistividad = 17; %(Ohm*cmil)/ft
            long = long*3.28084;
            r1 = Resistividad*long/secc_trans;
            if num_hilos > 3
               r1 = r1 + r1*0.05;
            else
               r1 = r1 + r1*0.04;
            end
            R_ca = r1;
        end
        
        function R_L = tempFunctionRes(app, R1, t1, t2)
            T = 228; %°C, para conductores de aluminio estirado
            R_L = R1*(T+t2)/(T+t1);
        end
        
        function DMG = DMG_square(app, D)
            a = D*D*sqrt(2*D^2);
            DMG = nthroot(a,3);
        end
        
        function DMG = DMG_equilat(app, D)
            DMG = D;
        end
        
        function DMG = DMG_straight(app, D)
            a = 2*D*D*D;
            DMG = nthroot(a,3);
        end
        
        function DMG = DMGbyDistribution(app, D, dis)
            a = 0;
            switch dis
                case 1
                    a = DMG_square(app, D);
                case 2
                    a = DMG_equilat(app, D);
                case 3
                    a = DMG_straight(app, D);
                case 4
                    a = DMG_straight(app, D);
                otherwise
                    a = 0;
            end
            DMG = a;
        end

        function XL_L = lineInductiveReactance(app, f, d, DMG_L, RMG_L)
            DMG_L = DMG_L*3.28084;
            l = (2*10^(-7))*log(DMG_L/RMG_L);   %inductividad por unidad de longitud en H/m
            L = l*d;                            %inductividad de la línea total en H
            XL_L = [2*pi*f*L, L];               %Reactancia de la línea en Ohms
        end
        
        function BC_L = lineCapacitiveSusceptance(app, f, d, DMG_C, RMG_C)
            DMG_C = DMG_C*3.28084;
            c = (2*pi*(8.85*10^(-12)))/(log(DMG_C/RMG_C));  %capacitancia por unidad de longitud en F/m
            C = c*d;                                   %capacitancia de la línea total en F
            BC_L = [2*pi*f*C, C];                           %Susceptancia de la línea en Ohms
        end

        function I_r = operationCurrent(app, V, P, fp)
            I = P/(abs(fp)*(V/sqrt(3)));
            ang = acosd(abs(fp));
            sign = fp/abs(fp);
            re = I*cos(ang);
            im = I*sin(ang);
            I_r = re + im*fp/abs(fp)*1i;
        end
        
        function T = lineMatrix(app, A, B, C, D, V_r, I_r)
            M = [A B; C D];
            R = [V_r; I_r];
            
            T = M*R;
        end
        
        function LS = line_Short(app, V_op, I_op, Z)
            LS = lineMatrix(app, 1, Z, 0, 1, V_op, I_op);
        end
        
        function LM = line_Medium(app, V_op, I_op, Z, Y)
            a = 1 + (Z*Y/2);
            c = Y*(1 + (Z*Y/4));
            LM = lineMatrix(app, a, Z, c, a, V_op, I_op);
        end
        
        
        function LL = line_Long(app, V_op, I_op, Z, Y)
            gl = sqrt(Z*Y);
            
            Z1 = Z*(sinh(gl)/gl);
            Y1 = Y*(tanh(gl/2)/(gl/2));
            
            a = 1 + (Z1*Y1/2);
            c = Y1*(1 + (Z1*Y1/4));
            
            LL = lineMatrix(app, a, Z1, c, a, V_op, I_op);
        end
        
        function R = results(app, T, V)
            %Magnitud de VT
            V_T_mag_s = abs(T(1,1));
            V_T_mag_m = abs(T(1,2));
            V_T_mag_l = abs(T(1,3));
            V_T_mag = [V_T_mag_s; V_T_mag_m; V_T_mag_l];
            
            %Fase de VT
            V_T_ph_s = atand(iamg(T(1,1))/real(T(1,1)));
            V_T_ph_m = atand(iamg(T(1,2))/real(T(1,2)));
            V_T_ph_l = atand(iamg(T(1,3))/real(T(1,3)));
            V_T_ph = [V_T_ph_s; V_T_ph_m; V_T_ph_l];
            
            %Magnitud de IT
            I_T_mag_s = abs(T(2,1));
            I_T_mag_m = abs(T(2,2));
            I_T_mag_l = abs(T(2,3));
            I_T_mag = [I_T_mag_s; I_T_mag_m; I_T_mag_l];
            
            %Fase de IT
            I_T_ph_s = atand(iamg(T(2,1))/real(T(2,1)));
            I_T_ph_m = atand(iamg(T(2,2))/real(T(2,2)));
            I_T_ph_l = atand(iamg(T(2,3))/real(T(2,3)));
            I_T_ph = [I_T_ph_s; I_T_ph_m; I_T_ph_l];
            
            %Caída de tensión en la línea
            V_op = V/sqrt(3);
            Vl_s = T(1,1)-V_op;
            Vl_m = T(1,2)-V_op;
            Vl_l = T(1,3)-V_op;
            
            %Magnitud
            V_L_mag_s = abs(Vl_s);
            V_L_mag_m = abs(Vl_m);
            V_L_mag_l = abs(Vl_l);
            V_L_mag = [V_L_mag_s; V_L_mag_m; V_L_mag_l];
            
            %Fase
            V_L_ph_s = atand(iamg(Vl_s)/real(Vl_s));
            V_L_ph_m = atand(iamg(Vl_m)/real(Vl_m));
            V_L_ph_l = atand(iamg(Vl_l)/real(Vl_l));
            V_L_ph = [V_L_ph_s; V_L_ph_m; V_L_ph_l];
            
            %Porcentaje de regulación
            Reg_s = ((T(1,1)-V_op)/T(1,1))*100;
            %Faltan las otras regulaciones
            %Y la eficiencia
            
            Reg = [Reg_s; 0; 0];
            
            R = [V_T_mag, V_T_ph, I_T_mag, I_T_ph, V_L_mag, V_L_ph, Reg];
        end
        
        function CambiarTablaNodos(app)
            Cabecera = {'# Nodo'; 'Nombre'; 'Tipo de nodo'; '|V| [kV]'; 'Delta [°]'; 'P [MVA]'; 'Q [MVAR]'};

            numNodos = [1:app.NumNodos]';
            Selec = {};
            Nombres = {};
            for i=1:app.NumNodos
                Selec{end+1} = 'Escoger';
                Nombres{end+1} = '-';
            end
            Nombres = Nombres';
            tiposNodos = categorical(Selec',{'Escoger','Slack','PQ','PV'});
            Vmag = zeros(app.NumNodos, 1);
            Vang = zeros(app.NumNodos, 1);
            P = zeros(app.NumNodos, 1);
            Q = zeros(app.NumNodos, 1);
            t = table(numNodos, Nombres, tiposNodos, Vmag, Vang, P, Q);
            
            app.TablaNodos.ColumnName = Cabecera;
            app.TablaNodos.Data = t;
            app.TablaNodos.ColumnEditable = [false true true true true true true];
        end
        
        
        function Tabla = GuardarNuevaLT(app, Tabla, LT_Data)
            
            if(app.Num_Linea == 1)      %Si la tabla está vacía
                numLinea = [];
                %Conductor = {};
                Longitud = [];
                %Temp = [];
                NodoA = [];
                NodoB = [];
                R = [];
                XL = [];
                BC = [];
            else                %Si no está vacía se extraen los datos que tiene
                numLinea = Tabla{1:end, {'numLinea'}};
                %Conductor = Tabla{1:end, {'Conductor'}};
                Longitud = Tabla{1:end, {'Longitud'}};
                %Temp = Tabla{1:end, {'Temp'}};
                NodoA = Tabla{1:end, {'NodoA'}};
                NodoB = Tabla{1:end, {'NodoB'}};
                R = Tabla{1:end, {'R'}};
                XL = Tabla{1:end, {'XL'}};
                BC = Tabla{1:end, {'BC'}};
            end
            
            %Se añaden los nuevos datos
            Cabecera = {'# Linea'; 'Longitud [km]'; 'Conecta nodo'; 'con nodo'; 'R [Ohms]'; 'XL [Ohms]'; 'BC [Ohms]'};
            numLinea = [numLinea; LT_Data{1}];
            %Conductor(end+1) = LT_Data{2};
            Longitud = [Longitud; LT_Data{2}];
            %Temp = [Temp; LT_Data{3}];
            NodoA = [NodoA; LT_Data{3}];
            NodoB = [NodoB; LT_Data{4}];
            R = [R; LT_Data{5}];
            XL = [XL; LT_Data{6}];
            BC = [BC; LT_Data{7}];

            app.TablaLineas.ColumnName = Cabecera;
            Tabla = table(numLinea, Longitud, Temp, NodoA, NodoB, R, L, C);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFnc(app)
            app.RMG_glob = 0;
            app.DMG_glob = 0;
            app.Distrib_glob = 0;
            app.SeccTrans_glob = 0;
            app.ConductorTipo = 0;
            
            app.Long_in = 0;
            app.Vop_in = 0;
            app.Pcarga_in = 0;
            app.FP_in = 0;
            app.Temp_in = 0;
            app.Dist_in = 0;
            
            app.Res_calc = 0;
            app.Ind_calc = 0;
            app.Cap_calc = 0;
            app.XL = 0;
            app.BC = 0;
            app.Z_linea = 0;
            app.Y_linea = 0;
            app.V_op = 0; % Description
            app.I_op = 0;% Description
            
            app.NumNodos = 0;
            app.TablaNodos_in = 0;
            app.NombresNodos = 0;
            app.TiposNodos = 0;
            app.VmagNodos = 0;
            app.VangNodos = 0;
            app.PNodos = 0;
            app.QNodos = 0;
            
            app.Sbase = 0;
            app.Vbase = 0;
            
            app.TablaLT_in = 0;
            app.Num_Linea = 0;
            app.NodoConectadoA = 0; % Description
            app.NodoConectadoB = 0; % Description
            
            app.VmagNodosPU_solved = 0;
            app.VangNodosPU_solved = 0;
            app.PNodosPU_solved = 0;
            app.QNodosPU_solved = 0;
        end

        % Value changed function: TipoConductor
        function TipoConductorValueChanged(app, event)
            Conductores = {'Condor', 'Partridge', 'Dove', 'Drake', 'Bluejay', 'Pheasant', 'Bluebird'};
            RMG_cond = [0.0154, 0.0217, 0.0314, 0.0373, 0.0415, 0.0466, 0.0586];
            SeccTrans_cond = [800000, 266800, 556500, 795000, 1113000, 1272000, 2156000]; %[cmil]
            app.RMG_glob = 0;
            value = app.TipoConductor.Value;
            app.ConductorTipo = value;
            for i=1:7
               if strcmp(value, Conductores(i))
                   app.RMG_glob = RMG_cond(i);
                   app.SeccTrans_glob = SeccTrans_cond(i);
               end
            end
            %app.Cap.Value = num2str(app.SeccTrans_glob);
        end

        % Value changed function: Distribucion
        function DistribucionValueChanged(app, event)
            Distribuciones = {'Cuadrada', 'Equilatera', 'Vertical', 'Horizontal'};
            app.Distrib_glob = 0;
            value = app.Distribucion.Value;
            for i=1:4
               if strcmp(value, Distribuciones(i))
                   app.Distrib_glob = i;
               end
            end
            %DMGbyDistribution(app, app.D_glob)
            %app.Cap.Value = num2str(app.Distrib);
        end

        % Value changed function: LongitudLinea
        function LongitudLineaValueChanged(app, event)
            value = app.LongitudLinea.Value;
            app.Long_in = value;
        end

        % Callback function
        function TensionOperacionValueChanged(app, event)
            value = app.TensionOperacion.Value;
            app.Vop_in = value;
        end

        % Callback function
        function PotenciaCargaValueChanged(app, event)
            value = app.PotenciaCarga.Value;
            app.Pcarga_in = value;
        end

        % Callback function
        function FactorPotenciaValueChanged(app, event)
            value = app.FactorPotencia.Value;
            app.FP_in = value;
        end

        % Value changed function: TempOp
        function TempOpValueChanged(app, event)
            value = app.TempOp.Value;
            app.Temp_in = value;
        end

        % Value changed function: Distancia
        function DistanciaValueChanged(app, event)
            value = app.Distancia.Value;
            app.Dist_in = value;
        end

        % Button pushed function: BotonCalcRLC
        function BotonCalcRLCButtonPushed(app, event)
            app.Res_calc = tempFunctionRes(app, getRca(app, app.Long_in*1000, app.SeccTrans_glob, 100), 20, app.Temp_in);
            app.Res.Value = num2str(app.Res_calc); %Resistencia de la línea
            
            app.DMG_glob = DMGbyDistribution(app, app.Dist_in, app.Distrib_glob);

            L = lineInductiveReactance(app, 60, app.Long_in*1000, app.DMG_glob, app.RMG_glob);
            app.XL = L(1);          %Reactancia inductiva
            app.Ind_calc = L(2);    %Inductancia
            %app.Cap.Value = num2str(app.Ind_calc);
            app.Ind.Value = num2str(app.XL);

            C = lineCapacitiveSusceptance(app, 60, app.Long_in*1000, app.DMG_glob, app.RMG_glob);
            app.BC = C(1);          %Reactancia capacitiva
            app.Cap_calc = C(2);
            %app.Ind.Value = num2str(app.Cap_calc);
            app.Cap.Value = num2str(app.BC);
            
            %app.Z_linea = app.Res_calc + (1i*app.XL);
            %app.Y_linea = 1i*app.BC;
            
            %app.Num_Linea = app.Num_Linea + 1;
            %A = {app.ConductorTipo};
            LT_data = {app.Num_Linea, app.Long_in, app.Temp_in, app.ConNodoA.Value, app.ConNodoB.Value, str2double(app.Res.Value), app.XL, app.BC};
            
            app.TablaLT_in = GuardarNuevaLT(app, app.TablaLT_in, LT_data);
            
            app.TablaLineas.Data = app.TablaLT_in;
            
            %app.V_op = app.Vop_in/sqrt(3);
            %app.I_op = operationCurrent(app, app.Vop_in, app.Pcarga_in, app.FP_in);
            
            %s = line_Short(app, app.V_op, app.I_op, app.Z_linea);
            %m = line_Medium(app, app.V_op, app.I_op, app.Z_linea, app.Y_linea);
            %l = line_Long(app, app.V_op, app.I_op, app.Z_linea, app.Y_linea);
            
            %T = [s, m, l];
            %R = results(app, T, app.V_op*sqrt(3))
            
        end

        % Value changed function: NumNodosEditField
        function NumNodosEditFieldValueChanged(app, event)
            value = app.NumNodosEditField.Value;
            app.NumNodos = value;
            CambiarTablaNodos(app);
        end

        % Button pushed function: GuardarycontinuarButton
        function GuardarycontinuarButtonPushed(app, event)
            app.TablaNodos_in = app.TablaNodos.Data;    %Tabla con la información de los nodos
            Tabla = app.TablaNodos_in;
            app.Vbase = app.VoltBaseEditField.Value.*1e3;
            app.Sbase = app.PotBaseEditField.Value.*1e6;
            
            app.NombresNodos = Tabla{1:end, {'Nombres'}};
            app.TiposNodos = Tabla{1:end, {'tiposNodos'}};
            app.VmagNodos = Tabla{1:end, {'Vmag'}}.*1e3;
            app.VangNodos = Tabla{1:end, {'Vang'}};
            app.PNodos = Tabla{1:end, {'P'}}.*1e6;
            app.QNodos = Tabla{1:end, {'Q'}}.*1e6;
            
            app.ConNodoA.Items = app.NombresNodos;
            app.ConNodoA.ItemsData = [1:app.NumNodos];
            
            app.ConNodoB.Items = app.NombresNodos;
            app.ConNodoB.ItemsData = [1:app.NumNodos];
            
            app.Num_Linea = 1;
            app.IndicadorDeLT.Text = sprintf('La L.T. %d conecta a los nodos:', app.Num_Linea);
            
            %for i=1:7
            %    Tabla{1:end, Tabla.Properties.VariableNames(i)}
            %end
            
        end

        % Button pushed function: BotonAgregarLT
        function BotonAgregarLTButtonPushed(app, event)
            app.Num_Linea = app.Num_Linea + 1;
            app.IndicadorDeLT.Text = sprintf('La L.T. %d conecta a los nodos:', app.Num_Linea);
            
            app.Cap.Value = num2str(0);
            app.Res.Value = num2str(0);
            app.Ind.Value = num2str(0);
        end

        % Button pushed function: CalcularNewtonRaphsonButton
        function CalcularNewtonRaphsonButtonPushed(app, event)
            
            max_it = app.MaxItSpinner.Value;
            max_error = app.MaxErrorEditField.Value;
            Ybus_pu= GetYbus(app.TablaLT_in, app.Sbase, app.Vbase);
            Mag_pu = app.VmagNodos./app.Vbase;
            Ang_pu = deg2rad(app.VangNodos);
            
            P_pu = app.PNodos./app.Sbase;
            Q_pu = app.QNodos./app.Sbase;
            
            num = size(Ybus_pu);
            [EqSystem, SymsListEq, FullEqSystem, FullSymVarList] = GetPQ(Ybus_pu, Mag_pu, Ang_pu, P_pu, Q_pu, num(1));
            
            JacSystem = jacobian(EqSystem, SymsListEq);
            [IterationTable, ListaVars, FinalValues] = NewtonRaphsonMethod(EqSystem, JacSystem, SymsListEq, max_it, max_error);
            
            Cabecera = ['# Iteración', ListaVars, 'Error'];
            app.TablaIteraciones.ColumnName = Cabecera;
            app.TablaIteraciones.Data = IterationTable;
            
            % Se llena la tabla de resultados finales
            PS_Values = [Mag_pu, Ang_pu, P_pu, Q_pu];
            SistemaResuelto = solvePowerSystem(FullEqSystem, FinalValues, SymsListEq, PS_Values);
            app.VmagNodosPU_solved = SistemaResuelto(:,1);
            app.VangNodosPU_solved = rad2deg(SistemaResuelto(:,2));
            app.PNodosPU_solved = SistemaResuelto(:,3);
            app.QNodosPU_solved = SistemaResuelto(:,4);
            
            Num_Nodo = [1:size(app.VmagNodosPU_solved,1)]';
            NombreNodo = app.NombresNodos;
            TipoNodo = app.TiposNodos;
            Vmag = app.VmagNodosPU_solved;
            Vang = app.VangNodosPU_solved;
            P = app.PNodosPU_solved;
            Q = app.QNodosPU_solved;
            SP_Solved_Table = table(Num_Nodo, NombreNodo, TipoNodo, Vmag, Vang, P, Q);
            Cabecera = {'# Nodo'; 'Nombre'; 'Tipo de nodo'; '|V| [pu]'; 'Delta [°]'; 'P [pu]'; 'Q [pu]'};
            app.TablaResultadosFinales.Data = SP_Solved_Table;
            app.TablaResultadosFinales.ColumnName = Cabecera;
        end

        % Button pushed function: SeleccionararchivoNodosButton
        function SeleccionararchivoNodosButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx', '*.xls'},'File Selector');
            if file ~= 0
                archivoNodos = fullfile(path,file);
                TablaNod = readtable(archivoNodos);
                TablaNod.Vmag = str2double (TablaNod.Vmag);
                TablaNod.Vang = str2double (TablaNod.Vang);
                TablaNod.P = str2double (TablaNod.P);
                TablaNod.Q = str2double (TablaNod.Q);
                app.TablaNodos.Data = TablaNod;
            end
        end

        % Button pushed function: SeleccionararchivoLineasButton
        function SeleccionararchivoLineasButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx', '*.xls'},'File Selector');
            if file ~= 0
                archivoLineas = fullfile(path,file);
                TablaLin = readtable(archivoLineas);
                %TablaLin.Vmag = str2double (TablaLin.Vmag);
                %TablaLin.Vang = str2double (TablaNod.Vang);
                %TablaNod.P = str2double (TablaNod.P);
                %TablaNod.Q = str2double (TablaNod.Q);
                %app.TablaLT_in = TablaLin;
                Cabecera = {'# Linea'; 'Longitud [km]'; 'Conecta nodo'; 'con nodo'; 'R [Ohms]'; 'XL [Ohms]'; 'BC [Ohms]'};
                app.TablaLineas.ColumnName = Cabecera;
                app.TablaLineas.Data = TablaLin;
                app.TablaLT_in = TablaLin;
            end
        end

        % Button pushed function: ExportardatosButton
        function ExportardatosButtonPushed(app, event)
            [file,path] = uiputfile({'*.xlsx', '*.xls'},'File Selector');
            
            if file ~= 0
                archivoExportar = fullfile(path,file);
                j = 1;
                if app.InfoSPCheckBox.Value == true
                    writetable(app.TablaResultadosFinales.Data,archivoExportar,'Sheet',j);
                    j = j+1;
                end
                if app.InfoIterCheckBox.Value == true
                    writetable(app.TablaLT_in,archivoExportar,'Sheet',j);
                    j = j+1;
                end
                if app.InfoLTCheckBox.Value == true
                    writetable(app.TablaIteraciones.Data,archivoExportar,'Sheet',j);
                    j = j+1;
                end
                if app.InfoNodosCheckBox.Value == true
                    writetable(app.TablaNodos_in,archivoExportar,'Sheet',j);
                end
            end
        end

        % Button pushed function: EliminarLineaButton
        function EliminarLineaButtonPushed(app, event)
            
        end

        % Button pushed function: BotonGuardarLT
        function BotonGuardarLTButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Cálculo de Parámetros';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.Label_3 = uilabel(app.InicioTab);
            app.Label_3.Text = 'Universidad Nacional Autónoma de México';
            app.Label_4 = uilabel(app.InicioTab);
            app.Label_4.Text = 'Facultad de Ingeniería';
            app.Label_5 = uilabel(app.InicioTab);
            app.Label_5.Text = 'Sistemas Eléctricos de Potencia';
            app.Label_6 = uilabel(app.InicioTab);
            app.Label_6.Text = 'Dr. Rubén Tapia Olvera';
            app.Label_7 = uilabel(app.InicioTab);
            app.Label_7.Text = 'PRESENTAN:';
            app.Label_8 = uilabel(app.InicioTab);
            app.Label_8.Text = 'Castillo Alcántara Rodrigo';
            app.Label_9 = uilabel(app.InicioTab);
            app.Label_9.Text = 'Pérez López Héctor Miguel';
            app.Button = uibutton(app.InicioTab, 'push');
            app.Button.Icon = 'EscudoUNAM.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.InicioTab, 'push');
            app.Button_2.Icon = 'EscudoFI.png';
            app.Button_2.Text = '';
            app.NodosTab = uitab(app.TabGroup);
            app.NodosTab.Title = 'Nodos';
            app.Label_2 = uilabel(app.NodosTab);
            app.Label_2.Text = 'Ingrese datos de nodos del sistema de potencia';
            app.DatosdeentradaPanel = uipanel(app.NodosTab);
            app.DatosdeentradaPanel.Title = 'Datos de entrada';
            app.NmerodenodosEditFieldLabel = uilabel(app.DatosdeentradaPanel);
            app.NmerodenodosEditFieldLabel.Text = 'Número de nodos';
            app.NumNodosEditField = uieditfield(app.DatosdeentradaPanel, 'numeric');
            app.NumNodosEditField.Limits = [0 15];
            app.NumNodosEditField.ValueChangedFcn = createCallbackFcn(app, @NumNodosEditFieldValueChanged, true);
            app.VoltajebasekVLabel = uilabel(app.DatosdeentradaPanel);
            app.VoltajebasekVLabel.Text = 'Voltaje base [kV]';
            app.VoltBaseEditField = uieditfield(app.DatosdeentradaPanel, 'numeric');
            app.VoltBaseEditField.Limits = [0 10000];
            app.PotenciabaseMVALabel = uilabel(app.DatosdeentradaPanel);
            app.PotenciabaseMVALabel.Text = 'Potencia base [MVA]';
            app.PotBaseEditField = uieditfield(app.DatosdeentradaPanel, 'numeric');
            app.PotBaseEditField.Limits = [0 10000];
            app.TabladenodosPanel = uipanel(app.NodosTab);
            app.TabladenodosPanel.Title = 'Tabla de nodos';
            app.TablaNodos = uitable(app.TabladenodosPanel);
            app.TablaNodos.ColumnName = {'Nodo'};
            app.TablaNodos.RowName = {};
            app.CargardatosdesdearchivoPanel = uipanel(app.NodosTab);
            app.CargardatosdesdearchivoPanel.Title = 'Cargar datos desde archivo';
            app.GuardarycontinuarButton = uibutton(app.CargardatosdesdearchivoPanel, 'push');
            app.GuardarycontinuarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarycontinuarButtonPushed, true);
            app.GuardarycontinuarButton.Icon = 'LT4.jpg';
            app.GuardarycontinuarButton.Text = {'Guardar '; 'y continuar'};
            app.SeleccionararchivoNodosButton = uibutton(app.CargardatosdesdearchivoPanel, 'push');
            app.SeleccionararchivoNodosButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoNodosButtonPushed, true);
            app.SeleccionararchivoNodosButton.Icon = 'Load_File.png';
            app.SeleccionararchivoNodosButton.Text = 'Seleccionar archivo';
            app.DatosenPUNodosCheckBox = uicheckbox(app.CargardatosdesdearchivoPanel);
            app.DatosenPUNodosCheckBox.Text = 'Datos en P.U.';
            app.CalculoParametros = uitab(app.TabGroup);
            app.CalculoParametros.Title = 'Lineas';
            app.Label = uilabel(app.CalculoParametros);
            app.Label.Text = 'Cálculo de parámetros de Líneas de Transmisión';
            app.DatosEntrada = uipanel(app.CalculoParametros);
            app.DatosEntrada.Title = 'Datos de entrada';
            app.Panel = uipanel(app.DatosEntrada);
            app.Panel.Title = 'General';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Tipo de conductor';
            app.TipoConductor = uidropdown(app.Panel);
            app.TipoConductor.Items = {'Condor', 'Partridge', 'Drake', 'Bluejay', 'Bluebird', 'Dove', 'Pheasant'};
            app.TipoConductor.ValueChangedFcn = createCallbackFcn(app, @TipoConductorValueChanged, true);
            app.TipoConductor.Value = 'Partridge';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Longitud de la línea [km]';
            app.LongitudLinea = uieditfield(app.Panel, 'numeric');
            app.LongitudLinea.Limits = [0 1000];
            app.LongitudLinea.ValueDisplayFormat = '%5.2f';
            app.LongitudLinea.ValueChangedFcn = createCallbackFcn(app, @LongitudLineaValueChanged, true);
            app.Label8 = uilabel(app.Panel);
            app.Label8.Text = 'Temp. de operación [°C]';
            app.TempOp = uieditfield(app.Panel, 'numeric');
            app.TempOp.Limits = [0 150];
            app.TempOp.ValueDisplayFormat = '%1.2f';
            app.TempOp.ValueChangedFcn = createCallbackFcn(app, @TempOpValueChanged, true);
            app.DistribucionPanel = uipanel(app.DatosEntrada);
            app.DistribucionPanel.Title = 'Distribución';
            app.Label2 = uilabel(app.DistribucionPanel);
            app.Label2.Text = 'Distribución';
            app.Distribucion = uidropdown(app.DistribucionPanel);
            app.Distribucion.Items = {'Cuadrada', 'Equilatera', 'Vertical', 'Horizontal'};
            app.Distribucion.ValueChangedFcn = createCallbackFcn(app, @DistribucionValueChanged, true);
            app.Distribucion.Value = 'Cuadrada';
            app.Label9 = uilabel(app.DistribucionPanel);
            app.Label9.Text = 'Distancia [m]';
            app.Distancia = uieditfield(app.DistribucionPanel, 'numeric');
            app.Distancia.Limits = [0 50];
            app.Distancia.ValueDisplayFormat = '%1.2f';
            app.Distancia.ValueChangedFcn = createCallbackFcn(app, @DistanciaValueChanged, true);
            app.InterconexinPanel = uipanel(app.DatosEntrada);
            app.InterconexinPanel.Title = 'Interconexión';
            app.IndicadorDeLT = uilabel(app.InterconexinPanel);
            app.IndicadorDeLT.Text = 'La L.T. conecta a los nodos:';
            app.ConNodoA = uidropdown(app.InterconexinPanel);
            app.yLabel = uilabel(app.InterconexinPanel);
            app.yLabel.Text = 'y';
            app.ConNodoB = uidropdown(app.InterconexinPanel);
            app.RLC = uipanel(app.CalculoParametros);
            app.RLC.Title = 'Parámetros R, L y C calculados';
            app.ReactanciainductivaOhmsLabel_2 = uilabel(app.RLC);
            app.ReactanciainductivaOhmsLabel_2.Text = 'Reactancia inductiva [Ohms]';
            app.Ind = uitextarea(app.RLC);
            app.Ind.Editable = 'off';
            app.BotonCalcRLC = uibutton(app.RLC, 'push');
            app.BotonCalcRLC.ButtonPushedFcn = createCallbackFcn(app, @BotonCalcRLCButtonPushed, true);
            app.BotonCalcRLC.Icon = 'LT.jpg';
            app.BotonCalcRLC.Text = 'Calcular parámetros';
            app.BotonAgregarLT = uibutton(app.RLC, 'push');
            app.BotonAgregarLT.ButtonPushedFcn = createCallbackFcn(app, @BotonAgregarLTButtonPushed, true);
            app.BotonAgregarLT.Icon = 'LT2.jpg';
            app.BotonAgregarLT.Text = 'Agregar nueva L.T.';
            app.BotonGuardarLT = uibutton(app.RLC, 'push');
            app.BotonGuardarLT.ButtonPushedFcn = createCallbackFcn(app, @BotonGuardarLTButtonPushed, true);
            app.BotonGuardarLT.Icon = 'LT3.jpeg';
            app.BotonGuardarLT.Text = 'Guardar y continuar';
            app.LabelTextArea = uilabel(app.RLC);
            app.LabelTextArea.Text = 'Resistencia [Ohms]';
            app.Res = uitextarea(app.RLC);
            app.Res.Editable = 'off';
            app.ReactanciacapacitivaOhmsLabel = uilabel(app.RLC);
            app.ReactanciacapacitivaOhmsLabel.Text = 'Reactancia capacitiva [Ohms]';
            app.Cap = uitextarea(app.RLC);
            app.Cap.Editable = 'off';
            app.CargarparmetrosdesdearchivoPanel = uipanel(app.CalculoParametros);
            app.CargarparmetrosdesdearchivoPanel.Title = 'Cargar parámetros desde archivo';
            app.SeleccionararchivoLineasButton = uibutton(app.CargarparmetrosdesdearchivoPanel, 'push');
            app.SeleccionararchivoLineasButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionararchivoLineasButtonPushed, true);
            app.SeleccionararchivoLineasButton.Icon = 'Load_File.png';
            app.SeleccionararchivoLineasButton.Text = 'Seleccionar archivo';
            app.TablaLT = uitab(app.TabGroup);
            app.TablaLT.Title = 'Tabla L.T.';
            app.TablasResumen = uipanel(app.TablaLT);
            app.TablasResumen.Title = 'Tabla resumen de Líneas de Transmisión';
            app.TablaLineas = uitable(app.TablasResumen);
            app.TablaLineas.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TablaLineas.RowName = {};
            app.Label17 = uilabel(app.TablaLT);
            app.Label17.Text = 'Datos de líneas y nodos que conectan';
            app.EdicionLineas = uipanel(app.TablaLT);
            app.EdicionLineas.Title = 'Editar Líneas de Transmisión';
            app.EliminarlneaDropDownLabel = uilabel(app.EdicionLineas);
            app.EliminarlneaDropDownLabel.Text = 'Eliminar línea';
            app.EliminarLineaDropDown = uidropdown(app.EdicionLineas);
            app.EliminarLineaButton = uibutton(app.EdicionLineas, 'push');
            app.EliminarLineaButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarLineaButtonPushed, true);
            app.EliminarLineaButton.Icon = 'LineaCaida.png';
            app.EliminarLineaButton.Text = 'Eliminar';
            app.NRTab = uitab(app.TabGroup);
            app.NRTab.Title = 'NR';
            app.MtododeNewtonRaphsonPanel = uipanel(app.NRTab);
            app.MtododeNewtonRaphsonPanel.Title = 'Método de Newton - Raphson';
            app.CalcularNewtonRaphsonButton = uibutton(app.MtododeNewtonRaphsonPanel, 'push');
            app.CalcularNewtonRaphsonButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularNewtonRaphsonButtonPushed, true);
            app.CalcularNewtonRaphsonButton.Icon = 'Transformer.png';
            app.CalcularNewtonRaphsonButton.Text = {'Calcular'; ''};
            app.NmeromximodeiteracionesSpinnerLabel = uilabel(app.MtododeNewtonRaphsonPanel);
            app.NmeromximodeiteracionesSpinnerLabel.Text = 'Número máximo de iteraciones';
            app.MaxItSpinner = uispinner(app.MtododeNewtonRaphsonPanel);
            app.MaxItSpinner.Limits = [0 100];
            app.MaxItSpinner.RoundFractionalValues = 'on';
            app.MaxItSpinner.ValueDisplayFormat = '%.0f';
            app.UmbraldeerrormximoEditFieldLabel = uilabel(app.MtododeNewtonRaphsonPanel);
            app.UmbraldeerrormximoEditFieldLabel.Text = 'Umbral de error máximo';
            app.MaxErrorEditField = uieditfield(app.MtododeNewtonRaphsonPanel, 'numeric');
            app.MaxErrorEditField.Limits = [1e-12 1];
            app.MaxErrorEditField.Value = 1e-12;
            app.Label17_2 = uilabel(app.NRTab);
            app.Label17_2.Text = 'Solución por el método de Newton Raphson';
            app.PanelIteraciones = uipanel(app.NRTab);
            app.PanelIteraciones.Title = 'Resumen de iteraciones';
            app.TablaIteraciones = uitable(app.PanelIteraciones);
            app.TablaIteraciones.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TablaIteraciones.RowName = {};
            app.ResultadosTab = uitab(app.TabGroup);
            app.ResultadosTab.Title = 'Resultados';
            app.Label17_3 = uilabel(app.ResultadosTab);
            app.Label17_3.Text = 'Resumen de resultados del sistema de potencia';
            app.PanelTablaFinal = uipanel(app.ResultadosTab);
            app.PanelTablaFinal.Title = 'Resultados del sistema de potencia';
            app.TablaResultadosFinales = uitable(app.PanelTablaFinal);
            app.TablaResultadosFinales.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TablaResultadosFinales.RowName = {};
            app.ExportardatosPanel = uipanel(app.ResultadosTab);
            app.ExportardatosPanel.Title = 'Exportar datos';
            app.ExportardatosButton = uibutton(app.ExportardatosPanel, 'push');
            app.ExportardatosButton.ButtonPushedFcn = createCallbackFcn(app, @ExportardatosButtonPushed, true);
            app.ExportardatosButton.Icon = 'Exportar.jpg';
            app.ExportardatosButton.Text = 'Exportar datos';
            app.InfoNodosCheckBox = uicheckbox(app.ExportardatosPanel);
            app.InfoNodosCheckBox.Text = 'Información de nodos';
            app.InfoLTCheckBox = uicheckbox(app.ExportardatosPanel);
            app.InfoLTCheckBox.Text = 'Información de L.T.';
            app.InfoIterCheckBox = uicheckbox(app.ExportardatosPanel);
            app.InfoIterCheckBox.Text = 'Resumen de iteraciones';
            app.InclurenelreporteLabel = uilabel(app.ExportardatosPanel);
            app.InclurenelreporteLabel.Text = 'Incluír en el reporte:';
            app.InfoSPCheckBox = uicheckbox(app.ExportardatosPanel);
            app.InfoSPCheckBox.Text = 'Resumen de resultados';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calc_LT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFnc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end